classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        IK1Button              matlab.ui.control.Button
        q1EditFieldLabel       matlab.ui.control.Label
        q1EditField            matlab.ui.control.EditField
        q2EditFieldLabel       matlab.ui.control.Label
        q2EditField            matlab.ui.control.EditField
        q3EditFieldLabel       matlab.ui.control.Label
        q3EditField            matlab.ui.control.EditField
        q4EditFieldLabel       matlab.ui.control.Label
        q4EditField            matlab.ui.control.EditField
        q5EditFieldLabel       matlab.ui.control.Label
        q5EditField            matlab.ui.control.EditField
        q6EditFieldLabel       matlab.ui.control.Label
        q6EditField            matlab.ui.control.EditField
        q7EditFieldLabel       matlab.ui.control.Label
        q7EditField            matlab.ui.control.EditField
        IK2Button              matlab.ui.control.Button
        IK3Button              matlab.ui.control.Button
        IK4Button              matlab.ui.control.Button
        Image                  matlab.ui.control.Image
        RightPanel             matlab.ui.container.Panel
        InverseKinematicsControllerLabel  matlab.ui.control.Label
        UIAxes_q               matlab.ui.control.UIAxes
        ProfPaoloAugustoLabel  matlab.ui.control.Label
        ByHusamNujaimLabel     matlab.ui.control.Label
        UIAxes_p               matlab.ui.control.UIAxes
        UIAxes_o               matlab.ui.control.UIAxes
        UIAxes_dq              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
    end
    
    methods (Access = public)
        
        function [clientID,verp] = StartVerp(app,porta)
            vrep = remApi('remoteApi');   % using the prototype file (remoteApiProto.m)
            vrep.simxFinish(-1);        % just in case, close all opened connections
            clientID = vrep.simxStart('127.0.0.1',porta,true,true,5000,5);% start the simulation
            
            if (clientID>-1)
                disp('remote API server connected successfully');
            else
                disp('failed connecting to remote API server');
                DeleteVrep(app,clientID, vrep); %call the destructor!
            end
            % to change the simulation step time use this command below, a custom dt in v-rep must be selected,
            % and run matlab before v-rep otherwise it will not be changed
            % vrep.simxSetFloatingParameter(clientID, vrep.sim_floatparam_simulation_time_step, 0.002, vrep.simx_opmode_oneshot_wait);
            vrep.simxStartSimulation(clientID, vrep.simx_opmode_oneshot);
        end
        
        
        function DeleteVrep(app,clientID, vrep)
            vrep.simxPauseSimulation(clientID,vrep.simx_opmode_oneshot_wait); % pause simulation
            %vrep.simxStopSimulation(clientID,vrep.simx_opmode_oneshot_wait); % stop simulation
            vrep.simxFinish(clientID);  % close the line if still open
            vrep.delete();              % call the destructor!
            disp('simulation ended');
        end
        
        function my_set_joint_target_position(app,vrep, clientID, handle_joint, q)
            [m,n] = size(q);
            for i=1:n
                for j=1:m
                    err = vrep.simxSetJointPosition(clientID,handle_joint(j),q(j,i),vrep.simx_opmode_oneshot);
                    if (err ~= vrep.simx_error_noerror)
                        fprintf('failed to send joint angle q %d \n',j);
                    end
                end
            end

        end
        
        function handle_joint = my_get_handle_Joint(app,vrep,clientID)

            [~,handle_joint(1)] = vrep.simxGetObjectHandle(clientID,'Revolute_joint_1',vrep.simx_opmode_oneshot_wait);
            [~,handle_joint(2)] = vrep.simxGetObjectHandle(clientID,'Revolute_joint_2',vrep.simx_opmode_oneshot_wait);
            [~,handle_joint(3)] = vrep.simxGetObjectHandle(clientID,'Revolute_joint_3',vrep.simx_opmode_oneshot_wait);
            [~,handle_joint(4)] = vrep.simxGetObjectHandle(clientID,'Revolute_joint_4',vrep.simx_opmode_oneshot_wait);
            [~,handle_joint(5)] = vrep.simxGetObjectHandle(clientID,'Revolute_joint_5',vrep.simx_opmode_oneshot_wait);
            [~,handle_joint(6)] = vrep.simxGetObjectHandle(clientID,'Revolute_joint_6',vrep.simx_opmode_oneshot_wait);
            [~,handle_joint(7)] = vrep.simxGetObjectHandle(clientID,'Revolute_joint_7',vrep.simx_opmode_oneshot_wait);

        end

        
        function my_set_joint_signal_position(app,vrep, clientID, q)
            [~,n] = size(q);

            for i=1:n
                joints_positions = vrep.simxPackFloats(q(:,i)');
                [err]=vrep.simxSetStringSignal(clientID,'jointsAngles',joints_positions,vrep.simx_opmode_oneshot_wait);
                
                if (err~=vrep.simx_return_ok)
                    fprintf('failed to send the string signal of iteration %d \n',i);
                end
            end
            pause(8);% wait till the script receives all data, increase it if dt is too small or tf is too high

        end
        
        function angle = my_get_joint_target_position(app,clientID,vrep,handle_joint,n)
            for j=1:n
                vrep.simxGetJointPosition(clientID,handle_joint(j),vrep.simx_opmode_streaming);
            end
            
            pause(0.05);
            
            for j=1:n
                [err(j),angle(j)]=vrep.simxGetJointPosition(clientID,handle_joint(j),vrep.simx_opmode_buffer);
            end
            
            if (err(j)~=vrep.simx_return_ok)
                fprintf(' failed to get position of joint %d \n',j);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IK1Button
        function IK1ButtonPushed(app, event)
            q1 = convertCharsToStrings(app.q1EditField.Value);
            q2 = convertCharsToStrings(app.q2EditField.Value);
            q3 = convertCharsToStrings(app.q3EditField.Value);
            q4 = convertCharsToStrings(app.q4EditField.Value);
            q5 = convertCharsToStrings(app.q5EditField.Value);
            q6 = convertCharsToStrings(app.q6EditField.Value);
            q7 = convertCharsToStrings(app.q7EditField.Value);
            iq=[q1 q2 q3 q4 q5 q6 q7];
            
            [t, q,dq,p_err,o_err] =main1(iq);
            plot(app.UIAxes_q,t,q);
            plot(app.UIAxes_dq,t,dq);
            plot(app.UIAxes_p,t,p_err);
            plot(app.UIAxes_o,t,o_err);
            
          
        end

        % Button pushed function: IK2Button
        function IK2ButtonPushed(app, event)
            [t, q,dq,p_err,o_err] =main2();
            plot(app.UIAxes_q,t,q);
            plot(app.UIAxes_dq,t,dq);
            plot(app.UIAxes_p,t,p_err);
            plot(app.UIAxes_o,t,o_err);
        end

        % Button pushed function: IK3Button
        function IK3ButtonPushed(app, event)
            [t, q,dq,p_err,o_err] =main3();
            plot(app.UIAxes_q,t,q);
            plot(app.UIAxes_dq,t,dq);
            plot(app.UIAxes_p,t,p_err);
            plot(app.UIAxes_o,t,o_err);
        end

        % Button pushed function: IK4Button
        function IK4ButtonPushed(app, event)
            [t, q,dq,p_err,o_err] =main4();
            plot(app.UIAxes_q,t,q);
            plot(app.UIAxes_dq,t,dq);
            plot(app.UIAxes_p,t,p_err);
            plot(app.UIAxes_o,t,o_err);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {688, 688};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {201, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.IK1Button = uibutton(app.LeftPanel, 'push');
            app.IK1Button.ButtonPushedFcn = createCallbackFcn(app, @IK1ButtonPushed, true);
            app.IK1Button.Text = 'IK - 1';
            app.q1EditFieldLabel = uilabel(app.LeftPanel);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.LeftPanel, 'text');
            app.q1EditField.Value = '1.3439';
            app.q2EditFieldLabel = uilabel(app.LeftPanel);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.LeftPanel, 'text');
            app.q2EditField.Value = '-0.2967';
            app.q3EditFieldLabel = uilabel(app.LeftPanel);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.LeftPanel, 'text');
            app.q3EditField.Value = '0';
            app.q4EditFieldLabel = uilabel(app.LeftPanel);
            app.q4EditFieldLabel.Text = 'q4';
            app.q4EditField = uieditfield(app.LeftPanel, 'text');
            app.q4EditField.Value = '0.7505';
            app.q5EditFieldLabel = uilabel(app.LeftPanel);
            app.q5EditFieldLabel.Text = 'q5';
            app.q5EditField = uieditfield(app.LeftPanel, 'text');
            app.q5EditField.Value = '-1.6406';
            app.q6EditFieldLabel = uilabel(app.LeftPanel);
            app.q6EditFieldLabel.Text = 'q6';
            app.q6EditField = uieditfield(app.LeftPanel, 'text');
            app.q6EditField.Value = '1.3439';
            app.q7EditFieldLabel = uilabel(app.LeftPanel);
            app.q7EditFieldLabel.Text = 'q7';
            app.q7EditField = uieditfield(app.LeftPanel, 'text');
            app.q7EditField.Value = '1.2392';
            app.IK2Button = uibutton(app.LeftPanel, 'push');
            app.IK2Button.ButtonPushedFcn = createCallbackFcn(app, @IK2ButtonPushed, true);
            app.IK2Button.Text = 'IK - 2';
            app.IK3Button = uibutton(app.LeftPanel, 'push');
            app.IK3Button.ButtonPushedFcn = createCallbackFcn(app, @IK3ButtonPushed, true);
            app.IK3Button.Text = 'IK - 3';
            app.IK4Button = uibutton(app.LeftPanel, 'push');
            app.IK4Button.ButtonPushedFcn = createCallbackFcn(app, @IK4ButtonPushed, true);
            app.IK4Button.Text = 'IK - 4';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'Maia_vect_POS.png';
            app.RightPanel = uipanel(app.GridLayout);
            app.InverseKinematicsControllerLabel = uilabel(app.RightPanel);
            app.InverseKinematicsControllerLabel.Text = 'Inverse Kinematics Controller';
            app.UIAxes_q = uiaxes(app.RightPanel);
            title(app.UIAxes_q, 'Joints Position')
            xlabel(app.UIAxes_q, 'time(t)')
            ylabel(app.UIAxes_q, 'joints position(q)')
            app.ProfPaoloAugustoLabel = uilabel(app.RightPanel);
            app.ProfPaoloAugustoLabel.Text = 'Prof. Paolo Augusto';
            app.ByHusamNujaimLabel = uilabel(app.RightPanel);
            app.ByHusamNujaimLabel.Text = 'By/ Husam Nujaim';
            app.UIAxes_p = uiaxes(app.RightPanel);
            title(app.UIAxes_p, 'Position Error')
            xlabel(app.UIAxes_p, 'time(t)')
            ylabel(app.UIAxes_p, 'position error')
            app.UIAxes_o = uiaxes(app.RightPanel);
            title(app.UIAxes_o, 'Rotation Error')
            xlabel(app.UIAxes_o, 'time(t)')
            ylabel(app.UIAxes_o, 'rotation error')
            app.UIAxes_dq = uiaxes(app.RightPanel);
            title(app.UIAxes_dq, 'Joints Velocity')
            xlabel(app.UIAxes_dq, 'time(t)')
            ylabel(app.UIAxes_dq, 'joints velocity(dq)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end